classdef oncolytic_dynamics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        y0Slider                    matlab.ui.control.Slider
        y0SliderLabel               matlab.ui.control.Label
        z0Slider                    matlab.ui.control.Slider
        z0SliderLabel               matlab.ui.control.Label
        v0Slider                    matlab.ui.control.Slider
        v0SliderLabel               matlab.ui.control.Label
        x0Slider                    matlab.ui.control.Slider
        x0SliderLabel               matlab.ui.control.Label
        eSlider                     matlab.ui.control.Slider
        eSliderLabel                matlab.ui.control.Label
        nSlider                     matlab.ui.control.Slider
        nSliderLabel                matlab.ui.control.Label
        sSlider                     matlab.ui.control.Slider
        sSliderLabel                matlab.ui.control.Label
        omegaSlider                 matlab.ui.control.Slider
        omegaSliderLabel            matlab.ui.control.Label
        NSlider                     matlab.ui.control.Slider
        NSliderLabel                matlab.ui.control.Label
        deltaSlider                 matlab.ui.control.Slider
        deltaSliderLabel            matlab.ui.control.Label
        gammaSlider                 matlab.ui.control.Slider
        gammaSliderLabel            matlab.ui.control.Label
        betaSlider                  matlab.ui.control.Slider
        betaSliderLabel             matlab.ui.control.Label
        ImmunityconsideredDropDown  matlab.ui.control.DropDown
        ImmunityconsideredDropDownLabel  matlab.ui.control.Label
        alphaSlider                 matlab.ui.control.Slider
        alphaSliderLabel            matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function plotfun(app)
            alpha_value = app.alphaSlider.Value;
            beta_value = app.betaSlider.Value;
            gamma_value = app.gammaSlider.Value;
            delta_value = app.deltaSlider.Value;
            N_value = app.NSlider.Value;
            n_value = app.nSlider.Value;
            s_value = app.sSlider.Value;
            e_value = app.eSlider.Value;
            omega_value = app.omegaSlider.Value;
            x_initial = app.x0Slider.Value;
            y_initial = app.y0Slider.Value;
            z_initial = app.z0Slider.Value;
            v_initial = app.v0Slider.Value;
            t_end = 500;
            
            function_type = app.ImmunityconsideredDropDown.Value;
                if strcmp(function_type, 'nonimmune')
                    tspan = [0, t_end]; % domain for solutions
                    c0 = [x_initial, y_initial, v_initial]; % initial condition
                    dcdt = @(t,c) [alpha_value.*c(1)-beta_value.*c(1).*c(3); beta_value.*c(1).*c(3)-delta_value.*c(2); N_value*delta_value.*c(2)-gamma_value.*c(3)];
                    [t, c] = ode45(dcdt,tspan,c0);
                    % Plot x, y and v in terms of t
                    plot(app.UIAxes,c(:,1),'b');  %plot solution for x(t) in blue
                    hold(app.UIAxes,'on'); 
                    plot(app.UIAxes,c(:,2),'r');  %plot solution for y(t) in red
                    hold(app.UIAxes,'on'); 
                    plot(app.UIAxes,c(:,3),'g');  %plot solution for v(t) in green
                    legend(app.UIAxes,'uninfected cancer cells','infected cancer cells','oncolytic viruses','location', 'bestoutside');
                    hold(app.UIAxes,'off');
                    ymax = max(max(c))*1.05;
                    app.UIAxes.YLim = [0 ymax];
                    app.UIAxes.XLim = [0 t_end]; 
                    app.UIAxes.View = [0 90];
                    app.EditField.Value = sprintf('%.2f\n, %.2f\n, %.2f\n, %.2f\n',alpha_value, beta_value, gamma_value, delta_value)
                elseif strcmp(function_type, 'immune')
                    tspan = [0, t_end]; % domain for solutions
                    i0 = [x_initial, y_initial, z_initial, v_initial]; % initial condition
                    didt = @(t,i) [alpha_value.*i(1)-beta_value.*i(1).*i(4); beta_value.*i(1).*i(4)-delta_value.*i(2)-s_value.*i(2).*i(3); s_value.*i(2).*i(3)-e_value.*i(3)-omega_value.*i(3)^2; N_value*delta_value.*i(2)-gamma_value.*i(4)-n_value.*i(4).*i(3)];
                    [t, i] = ode45(didt,tspan,i0);
                    % Plot x, y, z and v in terms of t
                    plot(app.UIAxes,i(:,1),'b');  %plot solution for x(t) in blue
                    hold(app.UIAxes,'on'); 
                    plot(app.UIAxes,i(:,2),'r');  %plot solution for y(t) in red
                    hold(app.UIAxes,'on'); 
                    plot(app.UIAxes,i(:,3),'y');  %plot solution for z(t) in yellow
                    hold(app.UIAxes,'on'); 
                    plot(app.UIAxes,i(:,4),'g');  %plot solution for v(t) in green
                    hold(app.UIAxes,'off'); 
                    ymax = max(max(i))*1.05;
                    legend(app.UIAxes,'uninfected cancer cells','infected cancer cells','immune cells','oncolytic viruses','location', 'bestoutside');
                    app.UIAxes.YLim = [0 ymax];
                    app.UIAxes.XLim = [0 t_end]; 
                    app.UIAxes.View = [0 90];
                    app.EditField.Value = sprintf('%.2f\n',N_value)
                end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: alphaSlider
        function alphaSliderValueChanged(app, event)
            plotfun(app)
        end

        % Value changed function: ImmunityconsideredDropDown
        function ImmunityconsideredDropDownValueChanged(app, event)
            plotfun(app)
        end

        % Callback function
        function ViewButtonGroupSelectionChanged(app, event)
            plotfun(app)
        end

        % Value changed function: betaSlider
        function betaSliderValueChanged2(app, event)
            plotfun(app)            
        end

        % Value changed function: gammaSlider
        function gammaSliderValueChanged2(app, event)
            plotfun(app)            
        end

        % Value changed function: deltaSlider
        function deltaSliderValueChanged2(app, event)
            plotfun(app)            
        end

        % Value changed function: NSlider
        function NSliderValueChanged(app, event)
            plotfun(app)
        end

        % Value changed function: omegaSlider
        function omegaSliderValueChanged(app, event)
            plotfun(app)            
        end

        % Value changed function: eSlider
        function eSliderValueChanged(app, event)
            plotfun(app)            
        end

        % Value changed function: nSlider
        function nSliderValueChanged(app, event)
            plotfun(app)
        end

        % Value changed function: sSlider
        function sSliderValueChanged(app, event)
            plotfun(app)
        end

        % Value changed function: x0Slider
        function x0SliderValueChanged(app, event)
            plotfun(app)            
        end

        % Value changed function: y0Slider
        function y0SliderValueChanged(app, event)
            plotfun(app)
        end

        % Value changed function: z0Slider
        function z0SliderValueChanged(app, event)
            plotfun(app)
        end

        % Value changed function: v0Slider
        function v0SliderValueChanged(app, event)
            plotfun(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Dynamics')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Number of cells/viruses')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 500];
            app.UIAxes.YLim = [0 100];
            app.UIAxes.XTick = [0 50 100 150 200 250 300 350 400 450 500];
            app.UIAxes.XTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'; '300'; '350'; '400'; '450'; '500'};
            app.alphaSliderLabel = uilabel(app.UIFigure);
            app.alphaSliderLabel.Text = 'alpha';
            app.alphaSlider = uislider(app.UIFigure);
            app.alphaSlider.Limits = [0 1];
            app.alphaSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.alphaSlider.ValueChangedFcn = createCallbackFcn(app, @alphaSliderValueChanged, true);
            app.alphaSlider.MinorTicks = [];
            app.alphaSlider.Value = 0.1;
            app.ImmunityconsideredDropDownLabel = uilabel(app.UIFigure);
            app.ImmunityconsideredDropDownLabel.Text = 'Immunity considered';
            app.ImmunityconsideredDropDown = uidropdown(app.UIFigure);
            app.ImmunityconsideredDropDown.Items = {'nonimmune', 'immune'};
            app.ImmunityconsideredDropDown.ValueChangedFcn = createCallbackFcn(app, @ImmunityconsideredDropDownValueChanged, true);
            app.ImmunityconsideredDropDown.Value = 'nonimmune';
            app.betaSliderLabel = uilabel(app.UIFigure);
            app.betaSliderLabel.Text = 'beta';
            app.betaSlider = uislider(app.UIFigure);
            app.betaSlider.Limits = [0 1];
            app.betaSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.betaSlider.ValueChangedFcn = createCallbackFcn(app, @betaSliderValueChanged2, true);
            app.betaSlider.MinorTicks = [];
            app.betaSlider.Value = 0.01;
            app.gammaSliderLabel = uilabel(app.UIFigure);
            app.gammaSliderLabel.Text = 'gamma';
            app.gammaSlider = uislider(app.UIFigure);
            app.gammaSlider.Limits = [0 1];
            app.gammaSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.gammaSlider.ValueChangedFcn = createCallbackFcn(app, @gammaSliderValueChanged2, true);
            app.gammaSlider.MinorTicks = [];
            app.gammaSlider.Value = 0.5;
            app.deltaSliderLabel = uilabel(app.UIFigure);
            app.deltaSliderLabel.Text = 'delta';
            app.deltaSlider = uislider(app.UIFigure);
            app.deltaSlider.Limits = [0 1];
            app.deltaSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.deltaSlider.ValueChangedFcn = createCallbackFcn(app, @deltaSliderValueChanged2, true);
            app.deltaSlider.MinorTicks = [];
            app.deltaSlider.Value = 0.1;
            app.NSliderLabel = uilabel(app.UIFigure);
            app.NSliderLabel.Text = 'N';
            app.NSlider = uislider(app.UIFigure);
            app.NSlider.Limits = [0 10];
            app.NSlider.MajorTicks = [0 2 4 6 8 10];
            app.NSlider.ValueChangedFcn = createCallbackFcn(app, @NSliderValueChanged, true);
            app.NSlider.MinorTicks = [];
            app.NSlider.Value = 5;
            app.omegaSliderLabel = uilabel(app.UIFigure);
            app.omegaSliderLabel.Text = 'omega';
            app.omegaSlider = uislider(app.UIFigure);
            app.omegaSlider.Limits = [0 1];
            app.omegaSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.omegaSlider.ValueChangedFcn = createCallbackFcn(app, @omegaSliderValueChanged, true);
            app.omegaSlider.MinorTicks = [];
            app.omegaSlider.Value = 0.05;
            app.sSliderLabel = uilabel(app.UIFigure);
            app.sSliderLabel.Text = 's';
            app.sSlider = uislider(app.UIFigure);
            app.sSlider.Limits = [0 1];
            app.sSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.sSlider.ValueChangedFcn = createCallbackFcn(app, @sSliderValueChanged, true);
            app.sSlider.MinorTicks = [];
            app.sSlider.Value = 0.01;
            app.nSliderLabel = uilabel(app.UIFigure);
            app.nSliderLabel.Text = 'n';
            app.nSlider = uislider(app.UIFigure);
            app.nSlider.Limits = [0 1];
            app.nSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.nSlider.ValueChangedFcn = createCallbackFcn(app, @nSliderValueChanged, true);
            app.nSlider.MinorTicks = [];
            app.nSlider.Value = 0.05;
            app.eSliderLabel = uilabel(app.UIFigure);
            app.eSliderLabel.Text = 'e';
            app.eSlider = uislider(app.UIFigure);
            app.eSlider.Limits = [0 1];
            app.eSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.eSlider.ValueChangedFcn = createCallbackFcn(app, @eSliderValueChanged, true);
            app.eSlider.MinorTicks = [];
            app.eSlider.Value = 0.1;
            app.x0SliderLabel = uilabel(app.UIFigure);
            app.x0SliderLabel.Text = 'x(0)';
            app.x0Slider = uislider(app.UIFigure);
            app.x0Slider.MajorTicks = [0 20 40 60 80 100];
            app.x0Slider.ValueChangedFcn = createCallbackFcn(app, @x0SliderValueChanged, true);
            app.x0Slider.MinorTicks = [];
            app.x0Slider.Value = 100;
            app.v0SliderLabel = uilabel(app.UIFigure);
            app.v0SliderLabel.Text = 'v(0)';
            app.v0Slider = uislider(app.UIFigure);
            app.v0Slider.Limits = [0 50];
            app.v0Slider.MajorTicks = [0 10 20 30 40 50];
            app.v0Slider.ValueChangedFcn = createCallbackFcn(app, @v0SliderValueChanged, true);
            app.v0Slider.MinorTicks = [];
            app.v0Slider.Value = 1;
            app.z0SliderLabel = uilabel(app.UIFigure);
            app.z0SliderLabel.Text = 'z(0)';
            app.z0Slider = uislider(app.UIFigure);
            app.z0Slider.MajorTicks = [0 20 40 60 80 100];
            app.z0Slider.ValueChangedFcn = createCallbackFcn(app, @z0SliderValueChanged, true);
            app.z0Slider.MinorTicks = [];
            app.z0Slider.Value = 100;
            app.y0SliderLabel = uilabel(app.UIFigure);
            app.y0SliderLabel.Text = 'y(0)';
            app.y0Slider = uislider(app.UIFigure);
            app.y0Slider.Limits = [0 50];
            app.y0Slider.MajorTicks = [0 10 20 30 40 50];
            app.y0Slider.ValueChangedFcn = createCallbackFcn(app, @y0SliderValueChanged, true);
            app.y0Slider.MinorTicks = [];
            app.y0Slider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = oncolytic_dynamics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end